classdef EC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GRAPHButton_2               matlab.ui.control.Button
        GRAPHButton                 matlab.ui.control.Button
        ADDButton_4                 matlab.ui.control.Button
        ADDButton_3                 matlab.ui.control.Button
        ADDButton_2                 matlab.ui.control.Button
        FrecMediasAltasMagnitud     matlab.ui.control.Slider
        ADDButton                   matlab.ui.control.Button
        notrecordingLabel           matlab.ui.control.Label
        FrecAltasMagnitud           matlab.ui.control.Slider
        magnitudSlider_4Label       matlab.ui.control.Label
        FrecAltasButtonGroup        matlab.ui.container.ButtonGroup
        Button3_4                   matlab.ui.control.RadioButton
        RectangularButton_4         matlab.ui.control.RadioButton
        HanningButton_3             matlab.ui.control.RadioButton
        magnitudSlider_3Label       matlab.ui.control.Label
        FrecMediasAltasButtonGroup  matlab.ui.container.ButtonGroup
        Button3_3                   matlab.ui.control.RadioButton
        RectangularButton_3         matlab.ui.control.RadioButton
        HanningButton_4             matlab.ui.control.RadioButton
        magnitudSlider_2            matlab.ui.control.Slider
        magnitudSlider_2Label       matlab.ui.control.Label
        FrecMediasBajasButtonGroup  matlab.ui.container.ButtonGroup
        Button3_2                   matlab.ui.control.RadioButton
        RectangularButton_2         matlab.ui.control.RadioButton
        HanningButton_2             matlab.ui.control.RadioButton
        SegundosSpinner             matlab.ui.control.Spinner
        SegundosSpinnerLabel        matlab.ui.control.Label
        PLAYTRANSButton             matlab.ui.control.Button
        TransformadaButtonGroup_2   matlab.ui.container.ButtonGroup
        FaseButton_2                matlab.ui.control.RadioButton
        MagnitudButton_2            matlab.ui.control.RadioButton
        SignaButton                 matlab.ui.control.RadioButton
        magnitudSlider              matlab.ui.control.Slider
        magnitudSliderLabel         matlab.ui.control.Label
        FrecBajasButtonGroup        matlab.ui.container.ButtonGroup
        Button3                     matlab.ui.control.RadioButton
        RectangularButton           matlab.ui.control.RadioButton
        HanningButton               matlab.ui.control.RadioButton
        RESETTRANSFORMButton        matlab.ui.control.Button
        PLAYOGButton                matlab.ui.control.Button
        RECButton                   matlab.ui.control.Button
        OriginalButtonGroup         matlab.ui.container.ButtonGroup
        FaseButton                  matlab.ui.control.RadioButton
        MagnitudButton              matlab.ui.control.RadioButton
        SignalButton                matlab.ui.control.RadioButton
        UITRANS                     matlab.ui.control.UIAxes
        UIOG                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        recObj = audiorecorder; % Creando el objeto de audio
        fs;                     % Extrayendo la frecuencia de muestreo del objeto de grabación.
        datosGrabacion;         % Objeto que guarda los datos de la grabacion original.
        datosTransformados;     % Objeto que guarda los datos de la grabacion original.
        Fx;                     % Algoritmo para calcular los valores de la Transformada de Fourier
        Fxs;                    % Corrimiento de la transformada para obtener valores negativos y positivos.
        f;                      % Creación del vector de Frecuencias
        t;                      % Creando un vector de tiempo
        n;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECButton
        function RECButtonPushed(app, event)
            app.fs = app.recObj.SampleRate; % Extrayendo la frecuencia de muestreo del objeto de grabación.
            
            if app.SegundosSpinner.Value > 0
                % Recording de audio
                set(app.notrecordingLabel,'FontColor','red');
                set(app.notrecordingLabel,'Text','recording');
                recordblocking(app.recObj, app.SegundosSpinner.Value);    
                set(app.notrecordingLabel,'FontColor','#2e2828');
                set(app.notrecordingLabel,'Text','not recording');
                
                % Extracting the data (from the audio)
                app.datosGrabacion = getaudiodata(app.recObj);
                app.datosTransformados = getaudiodata(app.recObj);
                app.datosGrabacion = app.datosGrabacion ./ max(app.datosGrabacion);
                
                % Testing the audio
                sound(app.datosGrabacion,app.fs);
                
                % Process the data
                app.n = length(app.datosGrabacion);
                app.t = (0:app.n-1)/app.fs;
                app.Fx = fft(app.datosGrabacion);
                app.Fxs = fftshift(app.Fx);
                app.f = (-app.n/2:app.n/2-1)*app.fs/app.n;                
                
            end
        end

        % Button pushed function: PLAYOGButton
        function PLAYOGButtonPushed(app, event)
            sound(app.datosGrabacion,app.fs);
        end

        % Button pushed function: PLAYTRANSButton
        function PLAYTRANSButtonPushed(app, event)
            sound(app.datosTransformados,app.fs);
        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)
            
            if app.SignalButton.Value
                plot(app.UIOG,app.t,app.datosGrabacion);
            elseif app.FaseButton.Value
                plot(app.UIOG,app.f,angle(app.Fxs));
            elseif app.MagnitudButton.Value
                plot(app.UIOG,app.f,abs(app.Fxs));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIOG = uiaxes(app.UIFigure);
            xlabel(app.UIOG, 'X')
            ylabel(app.UIOG, 'Y')
            zlabel(app.UIOG, 'Z')
            app.UITRANS = uiaxes(app.UIFigure);
            xlabel(app.UITRANS, 'X')
            ylabel(app.UITRANS, 'Y')
            zlabel(app.UITRANS, 'Z')
            app.OriginalButtonGroup = uibuttongroup(app.UIFigure);
            app.OriginalButtonGroup.Title = 'Original';
            app.SignalButton = uiradiobutton(app.OriginalButtonGroup);
            app.SignalButton.Text = 'Signal';
            app.SignalButton.Value = true;
            app.MagnitudButton = uiradiobutton(app.OriginalButtonGroup);
            app.MagnitudButton.Text = 'Magnitud';
            app.FaseButton = uiradiobutton(app.OriginalButtonGroup);
            app.FaseButton.Text = 'Fase';
            app.RECButton = uibutton(app.UIFigure, 'push');
            app.RECButton.ButtonPushedFcn = createCallbackFcn(app, @RECButtonPushed, true);
            app.RECButton.Text = 'REC';
            app.PLAYOGButton = uibutton(app.UIFigure, 'push');
            app.PLAYOGButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYOGButtonPushed, true);
            app.PLAYOGButton.Text = {'PLAY OG'; ''};
            app.RESETTRANSFORMButton = uibutton(app.UIFigure, 'push');
            app.RESETTRANSFORMButton.Text = 'RESET TRANSFORM';
            app.FrecBajasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecBajasButtonGroup.Title = 'Frec Bajas';
            app.HanningButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.HanningButton.Value = true;
            app.RectangularButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.Button3 = uiradiobutton(app.FrecBajasButtonGroup);
            app.Button3.Text = 'Button3';
            app.magnitudSliderLabel = uilabel(app.UIFigure);
            app.magnitudSliderLabel.Text = 'magnitud';
            app.magnitudSlider = uislider(app.UIFigure);
            app.magnitudSlider.Limits = [0 2];
            app.magnitudSlider.Value = 1;
            app.TransformadaButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.TransformadaButtonGroup_2.Title = 'Transformada';
            app.SignaButton = uiradiobutton(app.TransformadaButtonGroup_2);
            app.SignaButton.Text = 'Signa';
            app.SignaButton.Value = true;
            app.MagnitudButton_2 = uiradiobutton(app.TransformadaButtonGroup_2);
            app.MagnitudButton_2.Text = 'Magnitud';
            app.FaseButton_2 = uiradiobutton(app.TransformadaButtonGroup_2);
            app.FaseButton_2.Text = 'Fase';
            app.PLAYTRANSButton = uibutton(app.UIFigure, 'push');
            app.PLAYTRANSButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYTRANSButtonPushed, true);
            app.PLAYTRANSButton.Text = 'PLAY TRANS';
            app.SegundosSpinnerLabel = uilabel(app.UIFigure);
            app.SegundosSpinnerLabel.Text = 'Segundos';
            app.SegundosSpinner = uispinner(app.UIFigure);
            app.SegundosSpinner.Limits = [1 Inf];
            app.SegundosSpinner.Value = 1;
            app.FrecMediasBajasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecMediasBajasButtonGroup.Title = 'Frec Medias Bajas';
            app.HanningButton_2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.HanningButton_2.Text = 'Hanning';
            app.HanningButton_2.Value = true;
            app.RectangularButton_2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.RectangularButton_2.Text = 'Rectangular';
            app.Button3_2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.Button3_2.Text = 'Button3';
            app.magnitudSlider_2Label = uilabel(app.UIFigure);
            app.magnitudSlider_2Label.Text = 'magnitud';
            app.magnitudSlider_2 = uislider(app.UIFigure);
            app.magnitudSlider_2.Limits = [0 2];
            app.magnitudSlider_2.Value = 1;
            app.FrecMediasAltasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecMediasAltasButtonGroup.Title = 'Frec Medias Altas';
            app.HanningButton_4 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.HanningButton_4.Text = 'Hanning';
            app.HanningButton_4.Value = true;
            app.RectangularButton_3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.RectangularButton_3.Text = 'Rectangular';
            app.Button3_3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.Button3_3.Text = 'Button3';
            app.magnitudSlider_3Label = uilabel(app.UIFigure);
            app.magnitudSlider_3Label.Text = 'magnitud';
            app.FrecAltasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecAltasButtonGroup.Title = 'Frec Altas';
            app.HanningButton_3 = uiradiobutton(app.FrecAltasButtonGroup);
            app.HanningButton_3.Text = 'Hanning';
            app.HanningButton_3.Value = true;
            app.RectangularButton_4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.RectangularButton_4.Text = 'Rectangular';
            app.Button3_4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.Button3_4.Text = 'Button3';
            app.magnitudSlider_4Label = uilabel(app.UIFigure);
            app.magnitudSlider_4Label.Text = 'magnitud';
            app.FrecAltasMagnitud = uislider(app.UIFigure);
            app.FrecAltasMagnitud.Limits = [0 2];
            app.FrecAltasMagnitud.Value = 1;
            app.notrecordingLabel = uilabel(app.UIFigure);
            app.notrecordingLabel.Text = 'not recording';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.Text = 'ADD';
            app.FrecMediasAltasMagnitud = uislider(app.UIFigure);
            app.FrecMediasAltasMagnitud.Limits = [0 2];
            app.FrecMediasAltasMagnitud.Value = 1;
            app.ADDButton_2 = uibutton(app.UIFigure, 'push');
            app.ADDButton_2.Text = 'ADD';
            app.ADDButton_3 = uibutton(app.UIFigure, 'push');
            app.ADDButton_3.Text = 'ADD';
            app.ADDButton_4 = uibutton(app.UIFigure, 'push');
            app.ADDButton_4.Text = 'ADD';
            app.GRAPHButton = uibutton(app.UIFigure, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = 'GRAPH';
            app.GRAPHButton_2 = uibutton(app.UIFigure, 'push');
            app.GRAPHButton_2.Text = 'GRAPH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end